classdef YouBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RandomMovementButton            matlab.ui.control.Button
        StopSensorButton                matlab.ui.control.Button
        UpdateIntervalsecondsEditField  matlab.ui.control.NumericEditField
        UpdateIntervalsecondsEditFieldLabel  matlab.ui.control.Label
        ActionLabel                     matlab.ui.control.Label
        Reconnect                       matlab.ui.control.Button
        EndSimulationButton             matlab.ui.control.Button
        Directions                      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        clientID
        sim remApi
        h
        open
        sensorHandle
        LaserTimer timer
        map
        vision
        mapInit logical = false;          
        continueExecute
        Running logical = false;
        TimerObj timer
        LogOddsMap
        inputVelocity
        inputAngularVelocity
        inputStop logical = false;
        sicko_handle = -1
        youBotHandle = -1
        LastCommand = ''
        step = 0
        TimerRandom timer

        currentTheta = 0
        dt = 0.2
        currentDistance = 0
        Robot_x = 0
        Robot_y = 0        
        Velocity = [0,0]
        angularVelocity = [0,0,0]
        inputRotationDirection = 0
        inputTranslationDirection = 0
        randomDirection
    end
    
    methods (Access = private)             
        function stepSimulation(app)
            if app.Running
                UpdateState(app);
                PlotState(app);
                NextAction(app);
                app.sim.simxSynchronousTrigger(app.clientID);
            end            
        end
        function stopSimulation(app)
            stop(app.LaserTimer);
            delete(app.LaserTimer);
            stop(app.TimerObj);
            delete(app.TimerObj);   
            app.sim.simxFinish(app.clientID);
            app.sim.simxStopSimulation(app.clientID,app.sim.simx_opmode_oneshot);
            close all force;          
        end 
        function getRandomDirection(app)
            positions = {"forward", "right", "left"};
            weights = [2, 1, 1];
            randomPosition = randsample(positions, 1, true, weights);
            app.randomDirection = randomPosition{1,1};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %inital visibility rules
            app.ActionLabel.Visible = false;
            app.Reconnect.Visible = "off";
            app.Directions.Visible = "off";
            app.EndSimulationButton.Visible = "off";
            app.ActionLabel.Visible = "off";
            app.mapInit = false;
            app.sim = remApi('remoteApi');
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1',19999,true,true,5000,5);             
            app.h = [0,0,0,0,0,0,0,0,0,0,0]; % 11 total joints
            app.open = true;
            if (app.clientID>-1)
                %could do something here. Not sure what i would do
                %success visibile rules
                r = app.sim.simxSynchronous(app.clientID, true);
                if r ~= 0
                  error('Failed to enable synchronous mode');
                end
                r = app.sim.simxStartSimulation(app.clientID, app.sim.simx_opmode_oneshot);
                app.sim.simxSynchronousTrigger(app.clientID);
                [r,app.h(1)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fl', app.sim.simx_opmode_blocking); % r is like a return code I think
                [r,app.h(2)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rl', app.sim.simx_opmode_blocking);
                [r,app.h(3)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rr', app.sim.simx_opmode_blocking); 
                [r,app.h(4)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fr', app.sim.simx_opmode_blocking);
                [r,app.youBotHandle] = app.sim.simxGetObjectHandle(app.clientID, 'youBot', app.sim.simx_opmode_blocking);                
                app.ActionLabel.Visible = false;
                app.Reconnect.Visible = "off";
                app.Directions.Visible = "on";
                app.EndSimulationButton.Visible = "on";
                app.ActionLabel.Visible = "on";
                app.Running = true;
                
                app.TimerObj = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 0.02, ... % Step every 0.2 sec
                         'TimerFcn', @(~,~) app.stepSimulation); 
                start(app.TimerObj);
                app.TimerRandom = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 1, ... 
                         'TimerFcn', @(~,~) app.getRandomDirection); 
                start(app.TimerRandom);
                app.Running = true;
            else
                app.Reconnect.Visible = "on";                
                disp('Failed connecting to remote API server');
            end            
        end

        % Button pushed function: EndSimulationButton
        function EndSimulationButtonPushed(app, event)
            %end the simulation
            if app.clientID > -1
                stopSimulation(app);
                close all force;
            end

        end

        % Button pushed function: Reconnect
        function ReconnectButtonPushed(app, event)
            %attempt reconnect by calling startFcn            
            startupFcn(app);
            if app.clientID < 0
                app.ActionLabel.Visible = "on";
                app.ActionLabel.Text = "Reconnect Failed";
            else
                app.ActionLabel.Text = "";
            end
        end

        % Value changed function: UpdateIntervalsecondsEditField
        function UpdateIntervalsecondsEditFieldValueChanged(app, event)
            value = app.UpdateIntervalsecondsEditField.Value;          
        end

        % Button pushed function: StopSensorButton
        function StopSensorButtonPushed(app, event)
            stopSimulation(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.clientID > -1
                key = event.Key;
                value = "nil";
                switch key
                    case {'leftarrow',"A"}
                        app.inputRotationDirection = -1;
                        app.LastCommand = 'Rotate';
                        value = "Rotating counter-clockwise";

                    case {'rightarrow', "D"}
                        app.inputRotationDirection = 1;
                        app.LastCommand = 'Rotate';
                        value = "Rotating clockwise";

                    case {'uparrow', "W"}
                        app.inputTranslationDirection = 1;
                        app.LastCommand = 'Translate';
                        value = "Moving Forward";

                    case {'downarrow', "S"}
                        app.inputTranslationDirection = -1;
                        app.LastCommand = 'Translate';
                        value = "Moving Backward";

                    case {'space',"X"}
                        app.LastCommand = 'Stop';
                        value = "Staying in place";
                    otherwise

                        % Code for other keys
                        disp(['Key pressed: ', key]);
                        value = strcat("Unknown Key: ",key);
                end
                app.ActionLabel.Text = value;
                app.ActionLabel.Visible = true;
            end
        end

        % Button pushed function: RandomMovementButton
        function RandomMovementButtonPushed(app, event)
            if app.RandomMovementButton.BackgroundColor == [0.96,0.96,0.96]
                app.RandomMovementButton.BackgroundColor = [0, 1.00, 1.00];
            else
                app.RandomMovementButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Directions = uilabel(app.UIFigure);
            app.Directions.Text = {'Left Arrow - Rotate Left'; 'Right Arrow - Rotate Right'; 'Up Arrow - Forward'; 'Down Arrow - Backward'; 'Spacebar - Stop in place'};
            app.EndSimulationButton = uibutton(app.UIFigure, 'push');
            app.EndSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @EndSimulationButtonPushed, true);
            app.EndSimulationButton.Text = 'End Simulation';
            app.Reconnect = uibutton(app.UIFigure, 'push');
            app.Reconnect.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.Reconnect.Visible = 'off';
            app.Reconnect.Text = 'Attempt Reconnect';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = '';
            app.UpdateIntervalsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateIntervalsecondsEditFieldLabel.Text = 'Update Interval (seconds)';
            app.UpdateIntervalsecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateIntervalsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateIntervalsecondsEditFieldValueChanged, true);
            app.UpdateIntervalsecondsEditField.Value = 0.2;
            app.StopSensorButton = uibutton(app.UIFigure, 'push');
            app.StopSensorButton.ButtonPushedFcn = createCallbackFcn(app, @StopSensorButtonPushed, true);
            app.StopSensorButton.Text = 'Stop Sensor';
            app.RandomMovementButton = uibutton(app.UIFigure, 'push');
            app.RandomMovementButton.ButtonPushedFcn = createCallbackFcn(app, @RandomMovementButtonPushed, true);
            app.RandomMovementButton.Text = 'Random Movement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YouBotApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end